classdef physiomon < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure            matlab.ui.Figure
    FileMenu            matlab.ui.container.Menu
    StartMenu           matlab.ui.container.Menu
    SaveMenu            matlab.ui.container.Menu
    QuitMenu            matlab.ui.container.Menu
    EditMenu            matlab.ui.container.Menu
    onSettingsEdit      matlab.ui.container.Menu
    onDeviceEdit        matlab.ui.container.Menu
    HelpMenu            matlab.ui.container.Menu
    aboutphysiomonMenu  matlab.ui.container.Menu
    Toolbar             matlab.ui.container.Toolbar
    startToolbar        matlab.ui.container.toolbar.PushTool
    saveToolbar         matlab.ui.container.toolbar.ToggleTool
    displayToolbar      matlab.ui.container.toolbar.PushTool
    deviceToolbat       matlab.ui.container.toolbar.PushTool
    infoToolbar         matlab.ui.container.toolbar.PushTool
    hiddenPanel         matlab.ui.container.Panel
    statusWindow        matlab.ui.control.EditField
    LED_1               matlab.ui.control.Lamp
    LED_2               matlab.ui.control.Lamp
    LED_3               matlab.ui.control.Lamp
  end

  
  properties (Access = private)

    statusBar               % shows information
    dlgDisplayEdit;         % dialog for editing display settings
    dlgDeviceEdit;          % dialog for device editing

    m_settings              % instance of <physiomon_settings>
    m_device                % instance of measure device calls
    m_channels              % instance of channels class
    m_displays              % instance of <displays>
    m_timer                 % instance of a timer
    
  end
  
  methods (Access = public)
    
    %% onConfigure
    %
    %   configures the device, channels and so on depending on the input of the *.INI
    %   files or the popup dialog boxes

    function onConfigure(app)
    
        canvasHandle = app.hiddenPanel;
        
        app.m_displays = app.m_displays.configure(app.m_settings,canvasHandle);
        app.m_device = app.m_device.configure(app.m_settings);
        app.m_channels = app.m_channels.configure(app.m_settings,app.m_device);
    end
    
    %% setSettings
    %
    %   sets the channel information, display information and event structure, obtained
    %   from the dialog box back in the instance of m_settings

    function app = setSettings(app,channelStruct,displayStruct,eventStruct)
      app.m_settings = app.m_settings.setChannels(channelStruct);
      app.m_settings = app.m_settings.setDisplays(displayStruct);
      app.m_settings = app.m_settings.setEvents(eventStruct);
    end

    %% getDevice
    %
    %   returns the private property <m_device>

    function device = getDevice(app)
      device = app.m_device;
    end
    
    %% setDevice
    %
    %   sets the private property <m_device>
    
    function app = setDevice(app,device)
      app.m_device = device;  
    end
      
    %% onTimer
    % 
    %    function is called at timer periods

    function onTimer(app)
      
      app.statusBar = app.statusBar.update();

      [app.m_device,app.m_channels] = app.m_device.read(app.m_channels);
      [app.m_displays,app.m_channels] = app.m_displays.plot(app.m_channels);
      
    end
    
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, start)
      
      
      % create the statusbar
      
      app.statusBar = statusbar(app.statusWindow,[app.LED_1, app.LED_2, app.LED_3]);

      % intialise

      app.m_settings = physiomon_settings();
      [app.m_settings,deviceName] = app.m_settings.iniRead();
     
      app.m_device = devPhysioDaq();
      app.m_device = app.m_device.iniRead(deviceName);
      app.m_device = app.m_device.initialise();

      app.m_displays = displays();
      app.m_channels = physiomon_channels(5);               % buffers of 5 seconds

      % and configure

      onConfigure(app);
      
      % and create the timer, every 100 ms

      app.m_timer = timer('period',0.1,'ExecutionMode','fixedRate','TimerFcn',@(~,~)app.onTimer());
      app.m_timer.start();

      % and ready

      app.statusBar = app.statusBar.setText("ready ...",5);

    end

    % Menu selected function: onSettingsEdit
    function onSettingsEditSelected(app, event)


      channels = app.m_settings.getChannels();
      displays = app.m_settings.getDisplays();
      events = app.m_settings.getEvents();

      analogChannels = app.m_device.getAnalogChannels;

      app.dlgDisplayEdit = settings_popup(app,channels,displays,events,analogChannels,[]);
    end

    % Menu selected function: onDeviceEdit
    function onDeviceEditMenuSelected(app, event)

      % input is the device

      app.dlgDeviceEdit = devphysiodaq_popup(app,app.m_device,[]);
      
    end

    % Menu selected function: aboutphysiomonMenu
    function aboutphysiomonMenuSelected(app, event)


    end

    % Callback function: QuitMenu, UIFigure
    function UIFigureCloseRequest(app, event)
      
      % stop the timer before deleting it

      app.m_timer.stop();
      delete(app.m_timer);

      % and delete the app
      delete(app)
      
    end

    % Menu selected function: StartMenu
    function onStart(app, event)
      
      started = app.m_device.isStarted();

      if (~started)
        app.statusBar.setLed(1,'on');
        app.statusBar = app.statusBar.setText("device started ...",5);
        app.StartMenu.Text = "Stop";
        app.m_device = app.m_device.setStartStop(true);
      else
        app.statusBar.setLed(1,'off');
        app.statusBar = app.statusBar.setText("device stopped ...",5);
        app.StartMenu.Text = "Start";
        app.m_device = app.m_device.setStartStop(false);
      end

    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'PhysioMon v3.01';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.FileMenu = uimenu(app.UIFigure);
      app.FileMenu.Text = 'File';
      app.StartMenu = uimenu(app.FileMenu);
      app.StartMenu.MenuSelectedFcn = createCallbackFcn(app, @onStart, true);
      app.StartMenu.Text = 'Start';
      app.SaveMenu = uimenu(app.FileMenu);
      app.SaveMenu.Text = 'Save';
      app.QuitMenu = uimenu(app.FileMenu);
      app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.QuitMenu.Separator = 'on';
      app.QuitMenu.Text = 'Quit';
      app.EditMenu = uimenu(app.UIFigure);
      app.EditMenu.Text = 'Edit';
      app.onSettingsEdit = uimenu(app.EditMenu);
      app.onSettingsEdit.MenuSelectedFcn = createCallbackFcn(app, @onSettingsEditSelected, true);
      app.onSettingsEdit.Text = 'settings';
      app.onDeviceEdit = uimenu(app.EditMenu);
      app.onDeviceEdit.MenuSelectedFcn = createCallbackFcn(app, @onDeviceEditMenuSelected, true);
      app.onDeviceEdit.Text = 'device';
      app.HelpMenu = uimenu(app.UIFigure);
      app.HelpMenu.Text = 'Help';
      app.aboutphysiomonMenu = uimenu(app.HelpMenu);
      app.aboutphysiomonMenu.MenuSelectedFcn = createCallbackFcn(app, @aboutphysiomonMenuSelected, true);
      app.aboutphysiomonMenu.Text = 'about physiomon';
      app.Toolbar = uitoolbar(app.UIFigure);
      app.startToolbar = uipushtool(app.Toolbar);
      app.saveToolbar = uitoggletool(app.Toolbar);
      app.displayToolbar = uipushtool(app.Toolbar);
      app.deviceToolbat = uipushtool(app.Toolbar);
      app.infoToolbar = uipushtool(app.Toolbar);
      app.LED_3 = uilamp(app.UIFigure);
      app.LED_2 = uilamp(app.UIFigure);
      app.LED_1 = uilamp(app.UIFigure);
      app.statusWindow = uieditfield(app.UIFigure, 'text');
      app.hiddenPanel = uipanel(app.UIFigure);
      app.hiddenPanel.Title = 'displays';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = physiomon(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end